classdef App_AllDemos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        HomePageTab                  matlab.ui.container.Tab
        HomeLabel                    matlab.ui.control.Label
        Home_Demo1Button             matlab.ui.control.Button
        Home_Demo6Button             matlab.ui.control.Button
        Home_Demo5Button             matlab.ui.control.Button
        Home_Demo3Button             matlab.ui.control.Button
        Home_Demo2Button             matlab.ui.control.Button
        Demo1Tab                     matlab.ui.container.Tab
        Demo1_HomeButton             matlab.ui.control.Button
        Demo1_NextdemoButton         matlab.ui.control.Button
        Demo1_TitleBlock             matlab.ui.control.EditField
        Demo1Label                   matlab.ui.control.Label
        Demo1_RunButton              matlab.ui.control.Button
        Demo1_UIAxes                 matlab.ui.control.UIAxes
        Demo2Tab                     matlab.ui.container.Tab
        Demo2_HomeButton             matlab.ui.control.Button
        Demo2_NextdemoButton         matlab.ui.control.Button
        Demo2_FrequencysliderSlider  matlab.ui.control.Slider
        FrequencysliderSliderLabel   matlab.ui.control.Label
        Demo2_RunDemo2startplottingButton  matlab.ui.control.Button
        Demo2Label                   matlab.ui.control.Label
        Demo2_SetFrequencyButton     matlab.ui.control.Button
        Demo2_UIAxes                 matlab.ui.control.UIAxes
        Demo3Tab                     matlab.ui.container.Tab
        Demo3_NextdemoButton         matlab.ui.control.Button
        D3Panel                      matlab.ui.container.Panel
        Demo3Label                   matlab.ui.control.Label
        Demo3_HomeButton             matlab.ui.control.Button
        Demo3_SwitchPhaseButton      matlab.ui.control.Button
        Demo3_SendDataButton         matlab.ui.control.Button
        Demo3_ConnectButton          matlab.ui.control.Button
        Demo3_UIAxes                 matlab.ui.control.UIAxes
        Demo4Tab                     matlab.ui.container.Tab
        Demo4Label                   matlab.ui.control.Label
        Demo4_HomeButton             matlab.ui.control.Button
        Demo4_NextdemoButton         matlab.ui.control.Button
        Demo4_InstructionLabel       matlab.ui.control.Label
        Demo4_SaveDataButton         matlab.ui.control.Button
        Demo4_TrialCounterLabel      matlab.ui.control.Label
        Demo4_ActionButton           matlab.ui.control.Button
        Demo4_StartTrialButton       matlab.ui.control.Button
        Demo4_DataPlotAxes           matlab.ui.control.UIAxes
        Demo4_CircleAxes             matlab.ui.control.UIAxes
        Demo5Tab                     matlab.ui.container.Tab
        Demo5Label                   matlab.ui.control.Label
        Demo5_HomeButton             matlab.ui.control.Button
        Demo5_StartPlottingButton    matlab.ui.control.Button
        Demo5_UIAxes                 matlab.ui.control.UIAxes
        Demo5_UIAxes_2               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        arduino

        %demo 3 stuff (arduino aswell but dont need double paste)
        electrodeButtons  % Array of button handles
        selectionPhase = "source";  
        selectedSourceElectrodes = []
        selectedMeasurementElectrodes = []

        %demo5 

        QuartileComponents
        QuartileIndex 
        TrialCount = 0
        MaxTrials  = 50
        PendingBaseline logical = false
        LastStimFrame double = []   
        QuarterStim
        QuarterBase
        PairingMap = struct('quarterIndex',{},'objectOnRow',{},'baselineRow',{}); 

    end
    

    %setting up the demo 3 buttons and parenting them to the tab 3 (panel)
    %only

    methods (Access = private)
        %switching tab function 
        function switchToTab(app, tab)
            app.IsProgrammaticTabSwitch = true;
            app.TabGroup.SelectedTab = tab;
            drawnow;                  
            app.IsProgrammaticTabSwitch = false;
        end


        function setupElectrodeButtons(app)
            % Only build once
            if ~isempty(app.electrodeButtons) && all(isvalid(app.electrodeButtons))
                return
            end

            % Electrode Buttons in a Circle (fixed positions)
            numElectrodes = 16;
            radius = 120;
            centerX = 490;
            centerY = 150;
            buttonSize = [30, 30];

            app.electrodeButtons = gobjects(1, numElectrodes);

            for k = 1:numElectrodes
                angle = 2*pi*(k-1)/numElectrodes;
                x = centerX + radius * cos(angle) - buttonSize(1)/2;
                y = centerY + radius * sin(angle) - buttonSize(2)/2;

                % Parent to the Demo 3 panel (NOT app.UIFigure)
                btn = uibutton('Parent', app.D3Panel, ...
                    'Position', [x, y, buttonSize], ...
                    'Text', num2str(k), ...
                    'ButtonPushedFcn', @(~,~) app.handleElectrodeButtonClick(k));

                app.electrodeButtons(k) = btn;
            end
        end

        %Demo3 button clicks of the elctrodes
        function handleElectrodeButtonClick(app, k)
            if app.selectionPhase == "source"
                %add to source electrodes araay 
                if ~ismember(k, app.selectedSourceElectrodes)
                    if numel(app.selectedSourceElectrodes) < 2
                        app.selectedSourceElectrodes(end+1) = k;
                        app.electrodeButtons(k).BackgroundColor = [0 0.6 1]; % blue 
                    else
                       %replace first element of source electrodes array
                        oldest = app.selectedSourceElectrodes(1);
                        app.selectedSourceElectrodes = [app.selectedSourceElectrodes(2), k];
                        app.electrodeButtons(oldest).BackgroundColor = [0.94 0.94 0.94]; % reset colour
                        app.electrodeButtons(k).BackgroundColor = [0 0.6 1];
                    end
                end

            elseif app.selectionPhase == "measurement"
                %add to measurement electrodes array
                if ~ismember(k, app.selectedMeasurementElectrodes)
                    if numel(app.selectedMeasurementElectrodes) < 2
                        app.selectedMeasurementElectrodes(end+1) = k;
                        app.electrodeButtons(k).BackgroundColor = [1 0.4 0.4]; % red 
                    else
                        % replace the first element of the array (i.e. the
                        % oldest selected button of the ones in the array)
                        % with the most recent
                        oldest = app.selectedMeasurementElectrodes(1);
                        app.selectedMeasurementElectrodes = [app.selectedMeasurementElectrodes(2), k];
                        app.electrodeButtons(oldest).BackgroundColor = [0.94 0.94 0.94];
                        app.electrodeButtons(k).BackgroundColor = [1 0.4 0.4];
                    end
                end
            end
        end


        %demo5
        function frame = getFrameWithRetry(app, ax)
            % Call backend and return one valid numeric row 
            maxTries = 3;
            pauseBetween = 0.18;   % seconds
            minLen = 32;

            for k = 1:maxTries
                try
                    f = demo_4_theQuartiles(ax);  
                    if isnumeric(f) && ~isempty(f)
                        f = f(:).';               
                        if numel(f) >= minLen
                            frame = f;
                            return;
                        end
                    end
                catch
                    % swallow and retry
                end
                pause(pauseBetween);
            end
            error('No valid frame') 
        end


        function backendPlot(app, fileName, ax)

        usebaseline = 1; % Boolean to decide if relative responses are used
        
        if ~(ischar(fileName) || isstring(fileName))
            error('backendPlot:BadArg','fileName must be char or string.');
        end
        fileName = char(fileName);  
        if nargin < 3 || isempty(ax)
            ax = app.Demo4_CircleAxes;    % default to UIAxes
        end

        
        S = load(fileName, 'QuarterStim', 'QuarterBase');
        
        try
            data1 = S.QuarterStim{1,1};
            data2 = S.QuarterStim{1,2};
            data3 = S.QuarterStim{1,3};
            data4 = S.QuarterStim{1,4};
        catch
            data1 = S.QuarterStim{1};
            data2 = S.QuarterStim{2};
            data3 = S.QuarterStim{3};
            data4 = S.QuarterStim{4};
        end

        alldata = [data1; data2; data3; data4];

        try
            data1 = S.QuarterBase{1,1};
            data2 = S.QuarterBase{1,2};
            data3 = S.QuarterBase{1,3};
            data4 = S.QuarterBase{1,4};
        catch
            data1 = S.QuarterBase{1};
            data2 = S.QuarterBase{2};
            data3 = S.QuarterBase{3};
            data4 = S.QuarterBase{4};
        end

        cats = [zeros(size(data1,1),1); ...
                ones(size(data2,1),1); ...
                2*ones(size(data3,1),1); ...
                3*ones(size(data4,1),1)];
        baselines = [data1; data2; data3; data4];

        if usebaseline
            alldata = alldata - baselines;
        end

        if isempty(alldata)
            cla(ax); title(ax,'No stimulus-on data yet'); grid(ax,'on'); return;
        end

        
        n = min(192, size(alldata,2));  
        if exist('fsrftest','file') == 2
            ranking = fsrftest(alldata, cats);
        else
            [~, ranking] = sort(var(alldata,0,1), 'descend'); 
        end
        X = normalize(alldata(:, ranking(1:n)));
        [~, score] = pca(X);

        % plot on UIAxes 
        cla(ax); hold(ax,'on');
        for q = 0:3
            idx = (cats == q);
            if any(idx)
                scatter(ax, score(idx,1), score(idx,2), 20, 'filled', ...
                        'DisplayName', sprintf('Q%d', q+1));
            end
        end
        xlabel(ax,'PCA1'); ylabel(ax,'PCA2');
        xlim(ax, [min(score(:, 1)) max(score(:, 1))]);
        ylim(ax, [min(score(:, 2)) max(score(:, 2))]);
        title(ax,'PCA scatter by quarter (stimulus ON)');
        grid(ax,'on'); legend(ax,'show','Location','best');
        hold(ax,'off');
        end
    %demo 4 end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %demo 3
            app.setupElectrodeButtons();
            
            %demo4
            ax = app.Demo4_CircleAxes;
            cla(ax);
            axis(ax, 'equal');
            xlim(ax, [-1.2 1.2]);
            ylim(ax, [-1.2 1.2]);
            set(ax, 'XTick', [], 'YTick', []);
            box(ax, 'off');
            hold(ax, 'on');

            % circle radius
            r = 1;

            % angle ranges for each quarter
            angle_ranges = {
                linspace(0, pi/2, 50);        % Q1: top-right
                linspace(pi/2, pi, 50);       % Q2: top-left
                linspace(pi, 3*pi/2, 50);     % Q3: bottom-left
                linspace(3*pi/2, 2*pi, 50);   % Q4: bottom-right
            };

            baseColor = [0.85 0.85 0.85];
            app.QuartileComponents = gobjects(1, 4); 

            for i = 1:4
                theta = angle_ranges{i};
                x = [0, r * cos(theta), 0];
                y = [0, r * sin(theta), 0];
                app.QuartileComponents(i) = patch(ax, x, y, baseColor, 'EdgeColor', 'k');
            end
            % Coordinates for label positions inside each quarter
            labelCoords = [
                0.5,  0.5;   % Q1
                -0.5,  0.5;   % Q2
                -0.5, -0.5;   % Q3
                0.5, -0.5    % Q4
                ];

            for i = 1:4
                text(app.Demo4_CircleAxes, ...
                    labelCoords(i,1), labelCoords(i,2), ...
                    sprintf('Quarter %d', i), ...
                    'FontSize', 14, 'FontWeight', 'bold', ...
                    'HorizontalAlignment', 'center', ...
                    'Color', [0.3 0.3 0.3]);
            end


            app.QuarterStim = cell(1,4);
            app.QuarterBase = cell(1,4);
            app.PendingBaseline = false;
            cla(app.Demo4_DataPlotAxes);
            app.Demo4_ActionButton.Enable = 'off';

            app.PairingMap = struct('quarterIndex',{},'objectOnRow',{},'baselineRow',{});
            %demo5 end

        end

        % Button pushed function: Demo1_RunButton
        function Demo1_RunButtonPushed(app, event)
            demo1_plot_raw_data(app, app.Demo1_UIAxes);
        end

        % Button pushed function: Demo2_SetFrequencyButton
        function Demo2_SetFrequencyButtonPushed(app, event)
            %set frequency to that on the slider and then connect to the
            %arduino
            freq = round(app.Demo2_FrequencysliderSlider.Value);

            if isempty(app.arduino)
                try
                    comport = readlines("COMdetails.txt");
                    app.arduino = serialport(comport, 115200);  
                    pause(1);
                    disp("Serial connection established.");
                catch ME
                    uialert(app.UIFigure, ME.message, 'Connection Error');
                return;
                end
            end


            %write frequency to the arduino
            writeline(app.arduino, sprintf('F:%d', freq));
 
            uialert(app.UIFigure, "Frequency Sent successfully.", "Connected");
            disp(['Sent frequency to Arduino: ', num2str(freq)]);
        end

        % Button pushed function: Demo2_RunDemo2startplottingButton
        function Demo2_RunDemo2startplottingButtonPushed(app, event)
            % read the frequency from the slider
            freq = round(app.Demo2_FrequencysliderSlider.Value);

                    %connect to the arduino
                    if isempty(app.arduino)
                        try
                            comport = readlines("COMdetails.txt");
                            app.arduino = serialport(comport, 115200); 
                            pause(1);
                        catch
                            uialert(app.UIFigure, 'Failed to connect to Arduino.', 'Error');
                        return;
                        end
                    end

      
            demo2_plot_raw_data(app.arduino, freq, app.Demo2_UIAxes);
        end

        % Button pushed function: Demo3_SwitchPhaseButton
        function Demo3_SwitchPhaseButtonPushed(app, event)
        % the toggle button
            if app.selectionPhase == "source"
                app.selectionPhase = "measurement";
                app.Demo3_SwitchPhaseButton.Text = "Switch to Source";
                uialert(app.UIFigure, 'Now select 2 measurement electrodes.', 'Measurement Selection');
            else
                app.selectionPhase = "source";
                app.Demo3_SwitchPhaseButton.Text = "Switch to Measurement";
                uialert(app.UIFigure, 'Now select 2 source electrodes.', 'Source Selection');
            end

        end

        % Button pushed function: Demo3_SendDataButton
        function Demo3_SendDataButtonPushed(app, event)
             % making sure both source and measure have 2 electrodes selected
            if numel(app.selectedSourceElectrodes) ~= 2 || numel(app.selectedMeasurementElectrodes) ~= 2
                uialert(app.UIFigure, 'Please select 2 source and 2 measurement electrodes.', 'Incomplete Selection');
                return;
            end

            %formatting message
            msg = sprintf('E:%d,%d;M:%d,%d\n', ...
                app.selectedSourceElectrodes(1), app.selectedSourceElectrodes(2), ...
                app.selectedMeasurementElectrodes(1), app.selectedMeasurementElectrodes(2));

            % send the data to Arduino over the serial
            if isempty(app.arduino) || ~isvalid(app.arduino)
                uialert(app.UIFigure, 'Arduino not connected.', 'Connection Error');
                return;
            end

            writeline(app.arduino, msg);
            disp(['Sent electrode config: ' msg]);

            %plotting 
            frequency = 10000;  
            demo3_plot_raw_data(app.arduino, frequency, app.Demo3_UIAxes);  

        end

        % Button pushed function: Demo3_ConnectButton
        function Demo3_ConnectButtonPushed(app, event)
            %the button to cnnect to the arduino
            try
                comport = readlines("COMdetails.txt");
                app.arduino = serialport(comport, 115200);  
                configureTerminator(app.arduino, "LF");
                flush(app.arduino);

                uialert(app.UIFigure, "Arduino connected successfully.", "Connected");
            catch ME
                uialert(app.UIFigure, "Failed to connect to Arduino.", "Connection Error");
                disp(ME.message);
            end
        end

        % Callback function
        function Demo3_SwitchPhaseButtonPushed2(app, event)
            %not in use
        end

        % Button pushed function: Demo5_StartPlottingButton
        function Demo5_StartPlottingButtonPushed(app, event)
            run_dual_plot(app.Demo5_UIAxes, app.Demo5_UIAxes_2);
        end

        % Button pushed function: Demo4_StartTrialButton
        function Demo4_StartTrialButtonPushed(app, event)
            % Reset all quarter colors first
            baseColor = [0.85 0.85 0.85];
            for i = 1:4
                set(app.QuartileComponents(i), 'FaceColor', baseColor);
            end

            % Randomly choose a quarter
            app.QuartileIndex = randi(4);

            % Highlight the selected quarter
            highlightColor = [1 0.6 0.6];  % Soft red
            set(app.QuartileComponents(app.QuartileIndex), 'FaceColor', highlightColor);
            % Update instruction text
            app.Demo4_InstructionLabel.Text = sprintf('Place the metal object on Quarter %d', app.QuartileIndex);
            %clear the raw data plot 
            cla(app.Demo4_DataPlotAxes);

            % button state
            app.Demo4_StartTrialButton.Enable = 'off';
            app.Demo4_ActionButton.Text = 'Generate Data';
            app.Demo4_ActionButton.Enable = 'on';
            app.PendingBaseline = false;
        end

        % Button pushed function: Demo4_ActionButton
        function Demo4_ActionButtonPushed(app, event)
            
            if isempty(app.QuartileIndex)
                uialert(app.UIFigure,'Please start a trial first.','No Quarter Selected');
                return;
            end

            q = app.QuartileIndex;

            % Debounce immediately so we don't double-trigger IO
            app.Demo4_ActionButton.Enable = 'off';
            drawnow;

            if ~app.PendingBaseline
                % Phase 1: object on
                try
                    stimFrame = app.getFrameWithRetry(app.Demo4_DataPlotAxes);

                catch ME
                    uialert(app.UIFigure, sprintf('Could not read data: %s', ME.message), 'Read Error');
                    app.Demo4_ActionButton.Enable = 'on';  % allow retry
                    return;  % do NOT advance state
                end

               
                stimFrame = double(stimFrame(:)).';

     
                % append to QuarterStim{q} with shape checks
                cur = app.QuarterStim{q};
                if isempty(cur)
                    app.QuarterStim{q} = stimFrame;
                else
                    if size(cur,2) ~= numel(stimFrame)
                        uialert(app.UIFigure, sprintf('Frame length mismatch in Q%d. Expected %d, got %d.', ...
                            q, size(cur,2), numel(stimFrame)), 'Shape Mismatch');
                        app.Demo4_ActionButton.Enable = 'on';
                        return;
                    end
                    app.QuarterStim{q} = [cur; stimFrame]; %appending
                end

                %record mapping (quarter + stim row), baseline to be filled later
                objectOnRow = size(app.QuarterStim{q}, 1);
                app.PairingMap(end+1) = struct( ...
                    'quarterIndex', q, ...
                    'objectOnRow',  objectOnRow, ...
                    'baselineRow',  NaN );

                % Flip to baseline step (keep same quarter selected)
                app.PendingBaseline       = true;
                app.Demo4_ActionButton.Text     = 'Record Baseline';
                app.Demo4_InstructionLabel.Text = 'Remove the metal object, then press "Record Baseline".';
                app.Demo4_ActionButton.Enable   = 'on';   % enable for baseline press

            else
                %Phase 2: baseline reading
                try
                    baseFrame = app.getFrameWithRetry(app.Demo4_DataPlotAxes);

                catch ME
                    uialert(app.UIFigure, sprintf('Could not read baseline: %s', ME.message), 'Read Error');
                    app.Demo4_ActionButton.Enable = 'on';  % allow retry
                    return; % do NOT finish trial
                end

                baseFrame = double(baseFrame(:)).';

                curB = app.QuarterBase{q};
                if isempty(curB)
                    app.QuarterBase{q} = baseFrame;
                else
                    if size(curB,2) ~= numel(baseFrame)
                        uialert(app.UIFigure, sprintf('Baseline length mismatch in Q%d. Expected %d, got %d.', ...
                            q, size(curB,2), numel(baseFrame)), 'Shape Mismatch');
                        app.Demo4_ActionButton.Enable = 'on';
                        return;
                    end
                    app.QuarterBase{q} = [curB; baseFrame];
                end

                
                baselineRow = size(app.QuarterBase{q}, 1);
                pm = app.PairingMap;
                idxs = find([pm.quarterIndex] == q);
                k = [];
                if ~isempty(idxs)
                    for ii = fliplr(idxs)
                        if isnan(pm(ii).baselineRow)
                            k = ii; break;
                        end
                    end
                end
                if isempty(k)
                    
                    app.PairingMap(end+1) = struct('quarterIndex', q, 'objectOnRow', NaN, 'baselineRow', baselineRow);
                else
                    app.PairingMap(k).baselineRow = baselineRow;
                end

                % Wrap up trial 
                baseColor = [0.85 0.85 0.85];
                set(app.QuartileComponents(q),'FaceColor',baseColor);
                app.QuartileIndex  = [];
                app.PendingBaseline = false;

                app.TrialCount = app.TrialCount + 1;
                app.Demo4_TrialCounterLabel.Text = sprintf('Trial %d of %d', app.TrialCount, app.MaxTrials);

                if app.TrialCount >= app.MaxTrials
                    app.Demo4_InstructionLabel.Text = 'All trials complete!';
                    app.Demo4_StartTrialButton.Enable = 'off';
                    app.Demo4_ActionButton.Enable     = 'off';
                else
                    app.Demo4_InstructionLabel.Text  = 'Trial complete. Press "Start Trial" for the next one.';
                    app.Demo4_StartTrialButton.Enable = 'on';
                    app.Demo4_ActionButton.Enable     = 'off';
                end
            end
        end

        % Button pushed function: Demo4_SaveDataButton
        function Demo4_SaveDataButtonPushed(app, event)

            % Ask user where to save
            [file, path] = uiputfile('Demo4Data.mat','Save Data As');
            if isequal(file,0)
                return; % user cancelled
            end
            % Package data
            QuarterStim = app.QuarterStim;
            QuarterBase = app.QuarterBase;
            PairingMap  = app.PairingMap;

            pairsStim = []; pairsBase = []; pairsQuarter = [];
                    for k = 1:numel(PairingMap)
                n  = PairingMap(k).quarterIndex;
                r1 = PairingMap(k).objectOnRow;
                r2 = PairingMap(k).baselineRow;
                if ~isnan(r1) && ~isnan(r2)
                    pairsStim    = [pairsStim;  QuarterStim{n}(r1,:)];
                    pairsBase    = [pairsBase;  QuarterBase{n}(r2,:)];
                    pairsQuarter = [pairsQuarter; n];
                end
            end


            metadata = struct();
            metadata.fullFilePath = fullfile(path, file);   % full path string
            metadata.fileName     = file;                   % just file name
            metadata.folder       = path;                   % just folder
            metadata.savedAt      = datetime('now');


            save(fullfile(path,file), 'QuarterStim','QuarterBase','PairingMap', ...
                              'pairsStim','pairsBase','pairsQuarter', 'metadata');

            uialert(app.UIFigure,'Data saved (raw + paired arrays).','Save Complete');

            S = load(fullfile(path, file), 'metadata');
            fnameForBackend = S.metadata.fileName;

            cla(app.Demo4_CircleAxes);

            fullFile = fullfile(path, fnameForBackend);
            app.backendPlot(fullFile, app.Demo4_CircleAxes);
        end

        % Button pushed function: Demo1_NextdemoButton
        function Demo1_NextdemoButtonPushed(app, event)
            choice = questdlg('Would you like to move to the next demo?', ...
                'Tab Change', 'Yes','No','No');

                if strcmp(choice,'Yes')
                    %Clean up COM
                    try
                        if ~isempty(app.serialObj) && isvalid(app.serialObj)
                            fclose(app.serialObj);   
                            delete(app.serialObj);
                            app.serialObj = [];
                        end

                    catch ME
                        disp("Cleanup issue: " + ME.message)
                    end

                %switch tabs
                app.TabGroup.SelectedTab = app.Demo2Tab;
        
                end
                % No/Cancel- do nothing

        end

        % Button pushed function: Demo2_NextdemoButton
        function Demo2_NextdemoButtonPushed(app, event)
            choice = questdlg('Would you like to move to the next demo?', ...
                'Tab Change', 'Yes','No','No');

                if strcmp(choice,'Yes')
                    %Clean up COM
                    try
                        s = app.serialObj;
                        if ~isempty(s)
                            if isa(s,'serialport')                  
                                try, configureCallback(s,"off"); 
                                end
                                try, flush(s); 
                                end
                                app.serialObj = [];
                            elseif isa(s,'serial')                   
                                try, if strcmp(s.Status,'open'), fclose(s); 
                                end, 
                                end
                                delete(s);
                                app.serialObj = [];
                            else
                                app.serialObj = [];
                            end
                        end
                        catch ME
                            warning("Cleanup issue: %s", ME.message);
                    end
                        app.TabGroup.SelectedTab = app.Demo3Tab;
        
                end
                % No/Cancel- do nothing

        end

        % Button pushed function: Demo3_NextdemoButton
        function Demo3_NextdemoButtonPushed(app, event)
            choice = questdlg('Would you like to move to the next demo?', ...
                'Tab Change', 'Yes','No','No');

                if strcmp(choice,'Yes')
                    %Clean up COM
                    try
                        s = app.serialObj;
                        if ~isempty(s)
                            if isa(s,'serialport')                  
                                try, configureCallback(s,"off"); end
                                try, flush(s); end
                                        app.serialObj = [];
                            elseif isa(s,'serial')   
                                try, if strcmp(s.Status,'open'), fclose(s); end, end
                                delete(s);
                                app.serialObj = [];
                            else
                                app.serialObj = [];
                            end
                        end
                catch ME
                    warning("Cleanup issue: %s", ME.message);
                end

        %switch tabs
        app.TabGroup.SelectedTab = app.Demo4Tab;
        
        end
        % No/Cancel- do nothing
  
        end

        % Button pushed function: Demo4_NextdemoButton
        function Demo4_NextdemoButtonPushed(app, event)
                        choice = questdlg('Would you like to move to the next demo?', ...
                'Tab Change', 'Yes','No','No');

                if strcmp(choice,'Yes')
                    %Clean up COM
                    try
                        s = app.serialObj;
                        if ~isempty(s)
                            if isa(s,'serialport')                  
                                try, configureCallback(s,"off"); 
                                end
                                try, flush(s); 
                                end
                                        app.serialObj = [];
                            elseif isa(s,'serial')                   
                                try, if strcmp(s.Status,'open'), fclose(s); end, end
                                delete(s);
                                app.serialObj = [];
                            else
                                app.serialObj = [];
                            end
                        end
                catch ME
                    warning("Cleanup issue: %s", ME.message);
                end

                app.TabGroup.SelectedTab = app.Demo5Tab;
                end
                % No/Cancel- do nothing
        end

        % Button pushed function: Home_Demo1Button
        function Home_Demo1ButtonPushed(app, event)
            try
                s = app.serialObj;
                    if ~isempty(s)
                        if isa(s,'serialport')                  
                            try, configureCallback(s,"off"); 
                            end
                            try, flush(s); 
                            end
                                app.serialObj = [];          % drop reference- frees port

                        elseif isa(s,'serial')                   
                            try, if strcmp(s.Status,'open'), fclose(s); end, end
                                delete(s);
                                app.serialObj = [];
                            else
                                app.serialObj = [];
                            end
                        end
                        catch ME
                        warning("Cleanup issue: %s", ME.message);
                        end

                        app.TabGroup.SelectedTab = app.Demo1Tab;
        end

        % Button pushed function: Home_Demo2Button
        function Home_Demo2ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Demo2Tab;
        end

        % Button pushed function: Home_Demo3Button
        function Home_Demo3ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Demo3Tab;
        end

        % Button pushed function: Home_Demo5Button
        function Home_Demo5ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Demo4Tab;
        end

        % Button pushed function: Home_Demo6Button
        function Home_Demo6ButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.Demo5Tab;
        end

        % Button pushed function: Demo1_HomeButton
        function Demo1_HomeButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.HomePageTab;
        end

        % Button pushed function: Demo2_HomeButton
        function Demo2_HomeButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.HomePageTab;
        end

        % Button pushed function: Demo3_HomeButton
        function Demo3_HomeButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.HomePageTab;
        end

        % Button pushed function: Demo4_HomeButton
        function Demo4_HomeButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.HomePageTab;
        end

        % Button pushed function: Demo5_HomeButton
        function Demo5_HomeButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.HomePageTab;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            oldTab = event.OldValue;
            newTab = event.NewValue;

            choice = questdlg('Would you like to change to a new tab?',...
                'Tab Change',...
                'Yes', 'No','No');

                 if strcmp(choice, 'Yes')
                    try
                        if ~isempty(app.serialObj) && isvalid(app.serialObj)
                            fclose(app.serialObj);   
                            delete(app.serialObj);
                            app.serialObj = [];
                        end

                    catch ME
                        disp("Cleanup issue: " + ME.message)
                    end

                 else
                    app.TabGroup.SelectedTab = oldTab;

                end

        end

        % Callback function
        function Demo3_ConnectButtonPushed2(app, event)
            
        end

        % Button down function: Demo2Tab
        function Demo2TabButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.HomePageTab = uitab(app.TabGroup);
            app.HomePageTab.Title = 'Home Page';
            app.Home_Demo2Button = uibutton(app.HomePageTab, 'push');
            app.Home_Demo2Button.ButtonPushedFcn = createCallbackFcn(app, @Home_Demo2ButtonPushed, true);
            app.Home_Demo2Button.Text = 'Demo 2';
            app.Home_Demo3Button = uibutton(app.HomePageTab, 'push');
            app.Home_Demo3Button.ButtonPushedFcn = createCallbackFcn(app, @Home_Demo3ButtonPushed, true);
            app.Home_Demo3Button.Text = 'Demo 3';
            app.Home_Demo5Button = uibutton(app.HomePageTab, 'push');
            app.Home_Demo5Button.ButtonPushedFcn = createCallbackFcn(app, @Home_Demo5ButtonPushed, true);
            app.Home_Demo5Button.Text = 'Demo 4';
            app.Home_Demo6Button = uibutton(app.HomePageTab, 'push');
            app.Home_Demo6Button.ButtonPushedFcn = createCallbackFcn(app, @Home_Demo6ButtonPushed, true);
            app.Home_Demo6Button.Text = 'Demo 5';
            app.Home_Demo1Button = uibutton(app.HomePageTab, 'push');
            app.Home_Demo1Button.ButtonPushedFcn = createCallbackFcn(app, @Home_Demo1ButtonPushed, true);
            app.Home_Demo1Button.Text = 'Demo 1';
            app.HomeLabel = uilabel(app.HomePageTab);
            app.HomeLabel.Text = 'Home';
            app.Demo1Tab = uitab(app.TabGroup);
            app.Demo1Tab.Title = 'Demo 1';
            app.Demo1_UIAxes = uiaxes(app.Demo1Tab);
            title(app.Demo1_UIAxes, 'Live Data Plot')
            app.Demo1_RunButton = uibutton(app.Demo1Tab, 'push');
            app.Demo1_RunButton.ButtonPushedFcn = createCallbackFcn(app, @Demo1_RunButtonPushed, true);
            app.Demo1_RunButton.Text = 'Run Demo 1';
            app.Demo1Label = uilabel(app.Demo1Tab);
            app.Demo1Label.Text = 'Demo 1';
            app.Demo1_TitleBlock = uieditfield(app.Demo1Tab, 'text');
            app.Demo1_TitleBlock.Value = 'Click the button to run the demo and see the results!';
            app.Demo1_NextdemoButton = uibutton(app.Demo1Tab, 'push');
            app.Demo1_NextdemoButton.ButtonPushedFcn = createCallbackFcn(app, @Demo1_NextdemoButtonPushed, true);
            app.Demo1_NextdemoButton.Text = 'Next demo';
            app.Demo1_HomeButton = uibutton(app.Demo1Tab, 'push');
            app.Demo1_HomeButton.ButtonPushedFcn = createCallbackFcn(app, @Demo1_HomeButtonPushed, true);
            app.Demo1_HomeButton.Text = 'Home';
            app.Demo2Tab = uitab(app.TabGroup);
            app.Demo2Tab.Title = 'Demo 2';
            app.Demo2Tab.ButtonDownFcn = createCallbackFcn(app, @Demo2TabButtonDown, true);
            app.Demo2_UIAxes = uiaxes(app.Demo2Tab);
            title(app.Demo2_UIAxes, 'Plot Axes')
            zlabel(app.Demo2_UIAxes, 'Z')
            app.Demo2_SetFrequencyButton = uibutton(app.Demo2Tab, 'push');
            app.Demo2_SetFrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @Demo2_SetFrequencyButtonPushed, true);
            app.Demo2_SetFrequencyButton.Text = 'Set Frequency';
            app.Demo2Label = uilabel(app.Demo2Tab);
            app.Demo2Label.Text = 'Demo 2';
            app.Demo2_RunDemo2startplottingButton = uibutton(app.Demo2Tab, 'push');
            app.Demo2_RunDemo2startplottingButton.ButtonPushedFcn = createCallbackFcn(app, @Demo2_RunDemo2startplottingButtonPushed, true);
            app.Demo2_RunDemo2startplottingButton.Text = 'Run Demo2: start plotting';
            app.FrequencysliderSliderLabel = uilabel(app.Demo2Tab);
            app.FrequencysliderSliderLabel.Text = 'Frequency slider';
            app.Demo2_FrequencysliderSlider = uislider(app.Demo2Tab);
            app.Demo2_FrequencysliderSlider.Limits = [1500 100000];
            app.Demo2_FrequencysliderSlider.Value = 1500;
            app.Demo2_NextdemoButton = uibutton(app.Demo2Tab, 'push');
            app.Demo2_NextdemoButton.ButtonPushedFcn = createCallbackFcn(app, @Demo2_NextdemoButtonPushed, true);
            app.Demo2_NextdemoButton.Text = 'Next demo';
            app.Demo2_HomeButton = uibutton(app.Demo2Tab, 'push');
            app.Demo2_HomeButton.ButtonPushedFcn = createCallbackFcn(app, @Demo2_HomeButtonPushed, true);
            app.Demo2_HomeButton.Text = 'Home';
            app.Demo3Tab = uitab(app.TabGroup);
            app.Demo3Tab.Title = 'Demo3';
            app.D3Panel = uipanel(app.Demo3Tab);
            app.D3Panel.Title = 'D3Panel';
            app.Demo3_UIAxes = uiaxes(app.D3Panel);
            title(app.Demo3_UIAxes, 'Data Plot')
            app.Demo3_ConnectButton = uibutton(app.D3Panel, 'push');
            app.Demo3_ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @Demo3_ConnectButtonPushed, true);
            app.Demo3_ConnectButton.Text = 'Connect';
            app.Demo3_SendDataButton = uibutton(app.D3Panel, 'push');
            app.Demo3_SendDataButton.ButtonPushedFcn = createCallbackFcn(app, @Demo3_SendDataButtonPushed, true);
            app.Demo3_SendDataButton.Text = 'Send Data';
            app.Demo3_SwitchPhaseButton = uibutton(app.D3Panel, 'push');
            app.Demo3_SwitchPhaseButton.ButtonPushedFcn = createCallbackFcn(app, @Demo3_SwitchPhaseButtonPushed, true);
            app.Demo3_SwitchPhaseButton.Text = 'Switch to measurement';
            app.Demo3_HomeButton = uibutton(app.D3Panel, 'push');
            app.Demo3_HomeButton.ButtonPushedFcn = createCallbackFcn(app, @Demo3_HomeButtonPushed, true);
            app.Demo3_HomeButton.Text = 'Home';
            app.Demo3Label = uilabel(app.D3Panel);
            app.Demo3Label.Text = 'Demo 3';
            app.Demo3_NextdemoButton = uibutton(app.Demo3Tab, 'push');
            app.Demo3_NextdemoButton.ButtonPushedFcn = createCallbackFcn(app, @Demo3_NextdemoButtonPushed, true);
            app.Demo3_NextdemoButton.Text = 'Next demo';
            app.Demo4Tab = uitab(app.TabGroup);
            app.Demo4Tab.Title = 'Demo 4';
            app.Demo4_CircleAxes = uiaxes(app.Demo4Tab);
            app.Demo4_DataPlotAxes = uiaxes(app.Demo4Tab);
            app.Demo4_StartTrialButton = uibutton(app.Demo4Tab, 'push');
            app.Demo4_StartTrialButton.ButtonPushedFcn = createCallbackFcn(app, @Demo4_StartTrialButtonPushed, true);
            app.Demo4_StartTrialButton.Text = 'Start Trial';
            app.Demo4_ActionButton = uibutton(app.Demo4Tab, 'push');
            app.Demo4_ActionButton.ButtonPushedFcn = createCallbackFcn(app, @Demo4_ActionButtonPushed, true);
            app.Demo4_ActionButton.Text = 'Generate Data';
            app.Demo4_TrialCounterLabel = uilabel(app.Demo4Tab);
            app.Demo4_TrialCounterLabel.Text = 'Trial 0 out of 50';
            app.Demo4_SaveDataButton = uibutton(app.Demo4Tab, 'push');
            app.Demo4_SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @Demo4_SaveDataButtonPushed, true);
            app.Demo4_SaveDataButton.Text = 'Save Data';
            app.Demo4_InstructionLabel = uilabel(app.Demo4Tab);
            app.Demo4_InstructionLabel.Text = 'Press Start Trial to begin!';
            app.Demo4_NextdemoButton = uibutton(app.Demo4Tab, 'push');
            app.Demo4_NextdemoButton.ButtonPushedFcn = createCallbackFcn(app, @Demo4_NextdemoButtonPushed, true);
            app.Demo4_NextdemoButton.Text = 'Next demo';
            app.Demo4_HomeButton = uibutton(app.Demo4Tab, 'push');
            app.Demo4_HomeButton.ButtonPushedFcn = createCallbackFcn(app, @Demo4_HomeButtonPushed, true);
            app.Demo4_HomeButton.Text = 'Home';
            app.Demo4Label = uilabel(app.Demo4Tab);
            app.Demo4Label.Text = 'Demo 4';
            app.Demo5Tab = uitab(app.TabGroup);
            app.Demo5Tab.Title = 'Demo 5';
            app.Demo5_UIAxes_2 = uiaxes(app.Demo5Tab);
            title(app.Demo5_UIAxes_2, 'Visual Data Plotter')
            app.Demo5_UIAxes_2.XTick = [];
            app.Demo5_UIAxes_2.YTick = [];
            app.Demo5_UIAxes = uiaxes(app.Demo5Tab);
            title(app.Demo5_UIAxes, 'Raw Data')
            app.Demo5_StartPlottingButton = uibutton(app.Demo5Tab, 'push');
            app.Demo5_StartPlottingButton.ButtonPushedFcn = createCallbackFcn(app, @Demo5_StartPlottingButtonPushed, true);
            app.Demo5_StartPlottingButton.Text = 'Start Plotting';
            app.Demo5_HomeButton = uibutton(app.Demo5Tab, 'push');
            app.Demo5_HomeButton.ButtonPushedFcn = createCallbackFcn(app, @Demo5_HomeButtonPushed, true);
            app.Demo5_HomeButton.Text = 'Home';
            app.Demo5Label = uilabel(app.Demo5Tab);
            app.Demo5Label.Text = 'Demo 5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_AllDemos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end